classdef StethoscopeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BPMLabel                      matlab.ui.control.Label
        LowPassCutoffEditField        matlab.ui.control.NumericEditField
        LowPassCutoffEditFieldLabel   matlab.ui.control.Label
        HighPassCutoffEditField       matlab.ui.control.NumericEditField
        HighPassCutoffEditFieldLabel  matlab.ui.control.Label
        ReadDataButton                matlab.ui.control.Button
        LabelSwitch                   matlab.ui.control.Switch
        LabelSwitchLabel              matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ArduinoObj
        SerialPort = 'COM3'; 
        BaudRate = 9600;
        SamplingRate = 4000;
        RawData = [];
        DataTimer timer
        WindowSize = 500;
        HighPassCutoff = 100;
        LowPassCutoff = 400; 
        FilterOrder = 5; 
        bFilter
        aFilter
    end 

  methods (Access = private)
        function connectToArduino(app)
            try
                app.ArduinoObj = serialport(app.SerialPort, app.BaudRate, 'Timeout', 10);
                if ~isvalid(app.ArduinoObj) || ~strcmp(app.ArduinoObj.Status, 'open')
                    error('Failed to open the serial port.');
                end
                disp('Arduino connected');
                app.DataTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.1, ...
                    'TimerFcn', @(~,~)app.readDataFromArduino());
                start(app.DataTimer);
            catch ME
                disp(['Error connecting to Arduino. Make sure the port is correct. Error: ', ME.message]);
            end
        end
    end
    methods (Access = private)

        function createFilter(app)
    if app.HighPassCutoff > app.LowPassCutoff || app.HighPassCutoff <= 0 || app.LowPassCutoff >= app.SamplingRate/2
        error('Invalid cutoff frequencies. High-pass cutoff must be less than or equal to low-pass cutoff and both within the Nyquist frequency range.');
    end
    [app.bFilter, app.aFilter] = butter(app.FilterOrder, [app.HighPassCutoff, app.LowPassCutoff]/(app.SamplingRate/2), 'bandpass');
end

        function filteredSignal = applyFilter(app, data)
            filteredSignal = filter(app.bFilter, app.aFilter, data);
        end

    end

    methods (Access = private)

        function plotFilterSpectrum(app)
    if isempty(app.bFilter) || isempty(app.aFilter)
        error('Filter coefficients are empty. Call createFilter method first.');
    end
    freq = linspace(0, app.SamplingRate/2, 1000);
    h = fvtool(app.bFilter, app.aFilter);
    h.FrequencyRange = 'Specify freq. vector';
    title(app.UIAxes4, 'Bandpass Filter Frequency Response');
end

    end

    methods (Access = private)

        function updateFilter(app)
            [app.bFilter, app.aFilter] = butter(app.FilterOrder, [app.HighPassCutoff, app.LowPassCutoff]/(app.SamplingRate/2), 'bandpass');
        end

    end
methods (Access = private)
    function calculateHeartRate(app, filteredSignal)
    numSegments = 24;
    segmentLength = floor(length(filteredSignal) / numSegments);

    BPM_sum = 0;

    for i = 1:numSegments
        startIndex = (i - 1) * segmentLength + 1;
        endIndex = i * segmentLength;
        segment = filteredSignal(startIndex:endIndex);
        [~, peaks] = findpeaks(segment, 'MinPeakHeight', 0.5 * max(segment), 'MinPeakDistance', 400);

        timeBetweenPeaks = diff(peaks) / app.SamplingRate;

        segmentBPM = 60 / mean(timeBetweenPeaks);

        if ~isnan(segmentBPM) && segmentBPM >= 0 && segmentBPM <= 300
            BPM_sum = BPM_sum + segmentBPM;
        end
    end

    heartRate = BPM_sum / numSegments;

    disp(['Heart Rate: ', num2str(heartRate), ' BPM']);

    app.updateBPMLabel(heartRate);
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        plot(app.UIAxes, 1:500, zeros(1, 500), 'b');
        xlabel(app.UIAxes, 'Time');
        ylabel(app.UIAxes, 'Amplitude');
        title(app.UIAxes, 'Real-Time Signal');

        plot(app.UIAxes2, 1:500, zeros(1, 500), 'b');
        xlabel(app.UIAxes2, 'Frequency (Hz)');
        ylabel(app.UIAxes2, 'Magnitude');
        title(app.UIAxes2, 'Real-Time Signal FFT');

        plot(app.UIAxes3, 1:500, zeros(1, 500), 'r');
        xlabel(app.UIAxes3, 'Time');
        ylabel(app.UIAxes3, 'Amplitude');
        title(app.UIAxes3, 'Filtered Signal');

        plot(app.UIAxes4, 1:500, zeros(1, 500), 'r');
        xlabel(app.UIAxes4, 'Frequency (Hz)');
        ylabel(app.UIAxes4, 'Magnitude');
        title(app.UIAxes4, 'Filtered Signal FFT');

        start(app.DataTimer);
        createFilter(app);
        plotFilterSpectrum(app);
            
            app.HighPassCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.HighPassCutoffEditFieldLabel.HorizontalAlignment = 'right';
            app.HighPassCutoffEditFieldLabel.Position = [113 242 99 22];
            app.HighPassCutoffEditFieldLabel.Text = 'High-Pass Cut-off';

            app.HighPassCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighPassCutoffEditField.Position = [227 242 64 22];
            app.HighPassCutoffEditField.ValueChangedFcn = @(src, event) app.onHighPassCutoffChanged(src);
            app.HighPassCutoffEditField.Value = app.HighPassCutoff;

            app.LowPassCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.LowPassCutoffEditFieldLabel.HorizontalAlignment = 'right';
            app.LowPassCutoffEditFieldLabel.Position = [113 209 97 22];
            app.LowPassCutoffEditFieldLabel.Text = 'Low-Pass Cut-off';

            app.LowPassCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowPassCutoffEditField.Position = [225 209 66 22];
            app.LowPassCutoffEditField.ValueChangedFcn = @(src, event) app.onLowPassCutoffChanged(src);
            app.LowPassCutoffEditField.Value = app.LowPassCutoff;
            
            app.UIFigure.Visible = 'on';
        end
    end


   methods (Access = private)
       function updateBPMLabel(app, heartRate)
           app.BPMLabel.Text = ['BPM: ', num2str(heartRate/7.5)];
       end

        function onHighPassCutoffChanged(app, src)
            app.HighPassCutoff = src.Value;
            updateFilter(app);
            plotFilterSpectrum(app);
        end

        function onLowPassCutoffChanged(app, src)
            app.LowPassCutoff = src.Value;
            updateFilter(app);
            plotFilterSpectrum(app);
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
         app.connectToArduino();
            if ~isempty(app.ArduinoObj) && isvalid(app.ArduinoObj)
                disp('Arduino object created successfully.');
            else
                disp('Error: Arduino object not created.');
            end
            createFilter(app);
        end

        function readDataFromArduino(app)
    try
        while isvalid(app.ArduinoObj)
            rawData = readline(app.ArduinoObj);
            disp(['Raw Data: ', rawData]);

            cleanedData = regexprep(rawData, '[^0-9.]', '');

            numericData = str2double(cleanedData);

            app.RawData = [app.RawData, numericData];

            disp(['Length of RawData: ', num2str(length(app.RawData))]);

            plotRange = 1:length(app.RawData);

            plot(app.UIAxes, plotRange, app.RawData, 'b');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Real-Time Signal');
            
            startIndex = max(1, length(app.RawData) - app.WindowSize + 1);
            endIndex = length(app.RawData);
            if length(app.RawData) >= app.WindowSize
                filteredSignal = applyFilter(app, app.RawData(startIndex:endIndex));
                plotRangeFiltered = plotRange(end - length(filteredSignal) + 1:end);
                plot(app.UIAxes3, plotRangeFiltered, filteredSignal, 'r');
                xlabel(app.UIAxes3, 'Time');
                ylabel(app.UIAxes3, 'Amplitude');
                title(app.UIAxes3, 'Filtered Signal');

                minDistance = 400;
                threshold = 0.5;
                [~, peakLocations] = findpeaks(filteredSignal, 'MinPeakHeight', threshold, 'MinPeakDistance', minDistance);

                timeBetweenPeaks = diff(peakLocations) / app.SamplingRate;

                disp(['Length of timeBetweenPeaks: ', num2str(length(timeBetweenPeaks))]);

                heartRate = 60 / mean(timeBetweenPeaks);

                disp(['Calculated Heart Rate: ', num2str(heartRate), ' BPM']);

                disp(['Heart Rate: ', num2str(heartRate), ' BPM']);

                app.updateBPMLabel(heartRate);

                fftData = fft(app.RawData(startIndex:endIndex));

                N = length(fftData);
                fftMag = abs(fftData(1:N/2+1));
                freq = linspace(0, app.SamplingRate/2, N/2+1);

                plot(app.UIAxes2, freq, fftMag, 'b');
                xlabel(app.UIAxes2, 'Frequency (Hz)');
                ylabel(app.UIAxes2, 'Magnitude');
                title(app.UIAxes2, 'Real-Time Signal FFT');
                set(app.UIAxes2, 'YScale', 'log');

                fftFiltered = fft(filteredSignal);
                fftMagFiltered = abs(fftFiltered(1:N/2+1));
                freqFiltered = linspace(0, app.SamplingRate/2, N/2+1);
                plot(app.UIAxes4, freqFiltered, fftMagFiltered, 'r');
                xlabel(app.UIAxes4, 'Frequency (Hz)');
                ylabel(app.UIAxes4, 'Magnitude');
                title(app.UIAxes4, 'Filtered Signal FFT');
                set(app.UIAxes4, 'YScale', 'log');
            end

        end
    catch ME
        disp(getReport(ME));
        app.disconnectFromArduino();
    end
        end

        % Value changed function: LabelSwitch
        function LabelSwitchValueChanged(app, event)
            if app.LabelSwitch.Value
                disp('Stethoscope opened');
            else
                disp('Stethoscope closed');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real-Time Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Real-Time Signal FFT')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Filtered Signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Filtered Signal FFT')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.LabelSwitchLabel = uilabel(app.UIFigure);
            app.LabelSwitch = uiswitch(app.UIFigure, 'slider');
            app.LabelSwitch.ValueChangedFcn = createCallbackFcn(app, @LabelSwitchValueChanged, true);
            app.ReadDataButton = uibutton(app.UIFigure, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            app.HighPassCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.HighPassCutoffEditFieldLabel.Text = 'High-Pass Cut-off';
            app.HighPassCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighPassCutoffEditField.Value = 100;
            app.LowPassCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.LowPassCutoffEditFieldLabel.Text = 'Low-Pass Cut-off';
            app.LowPassCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowPassCutoffEditField.Value = 400;
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM:                ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StethoscopeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end